classdef robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StopButton                 matlab.ui.control.Button
        SingularityWarningLabel    matlab.ui.control.Label
        TorqueSwitch               matlab.ui.control.Switch
        TorqueSwitchLabel          matlab.ui.control.Label
        StatusPanel                matlab.ui.container.Panel
        StatusLabel                matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        ControlPanel               matlab.ui.container.Panel
        GoButton                   matlab.ui.control.Button
        ZeroButton                 matlab.ui.control.Button
        InitButton                 matlab.ui.control.Button
        ProgramArgumentsPanel      matlab.ui.container.Panel
        ProgramArgumentsLabel      matlab.ui.control.Label
        ProgramArgumentsEditField  matlab.ui.control.EditField
        ProgramSelectPanel         matlab.ui.container.Panel
        ProgramSelectLabel         matlab.ui.control.Label
        ProgramSelectDropdown      matlab.ui.control.DropDown
        PortDropDown               matlab.ui.control.DropDown
        PortDropDownLabel          matlab.ui.control.Label
        ConnectDisconnectButton    matlab.ui.control.Button
    end


    properties (Access = private)
        launcher;
    end

    methods (Access = private)

        function updateGUI(app)

            if isempty(app.launcher)
                % If no Launcher is initialized (disconnected)
                app.ControlPanel.Visible = 'off';
                app.ConnectDisconnectButton.Text = 'Connect';
                app.TorqueSwitch.Visible = 'off';
                app.SingularityWarningLabel.Visible = 'off';
                app.StopButton.Visible = 'off';
                app.StatusLabel.Text = 'disconnected';
                app.StatusLamp.Color = [1,0,0];
                
            else
                % If no Launcher is initialized (connected)
                app.ConnectDisconnectButton.Text = 'Disconnect';
                app.TorqueSwitch.Visible = 'on';
                app.SingularityWarningLabel.Visible = 'on';
                app.StatusLabel.Text = app.launcher.status;

                % Update Singularity Warning
                if app.launcher.singularityWarning
                    app.SingularityWarningLabel.Text = "Singularity Warning";
                    app.SingularityWarningLabel.FontColor = [1,0,0];
                else
                    app.SingularityWarningLabel.Text = "";
                end

                if strcmp(app.TorqueSwitch.Value, 'On')
                    % If torque is enabled, show control panel
                    app.ControlPanel.Visible = 'on';
                else
                    % If torque is disabled, hide control panel
                    app.ControlPanel.Visible = 'off';
                end

                % Update Status Lamp
                if strcmp(app.launcher.status, 'busy')
                    % When there is no program running
                    app.StatusLamp.Color = [0,0,1];
                end
                if strcmp(app.launcher.status, 'ready')
                     % When there is no program running
                    app.StatusLamp.Color = [0,1,0];
                end

            end

        end

        function is_valid = checkProgramArguments(app)
            selectedProgram = app.ProgramSelectDropdown.Value;
            arguments = app.ProgramArgumentsEditField.Value;

            % Initialize validity flag
            is_valid = false;

            switch selectedProgram
                case 'Set_Joints'
                    % Expecting 4 doubles (including negatives) separated by commas or semicolons
                    expression = '^(-?\d+(\.\d+)?[,;] *){3}-?\d+(\.\d+)?$';
                    if regexp(arguments, expression)
                        is_valid = true;
                    else
                        % Display UI warning for incorrect format
                        uialert(app.UIFigure, ...
                            'Please enter 4 joint angles (including negatives) separated by commas or semicolons.', ...
                            'Invalid Input', ...
                            'Icon', 'warning');
                    end

                case 'Set_Position'
                    % Expecting 3 doubles (including negatives) separated by commas or semicolons
                    expression = '^(-?\d+(\.\d+)?[,;] *){2}-?\d+(\.\d+)?$';
                    if regexp(arguments, expression)
                        is_valid = true;
                    else
                        % Display UI warning for incorrect format
                        uialert(app.UIFigure, ...
                            'Please enter 3 position coordinates (x, y, z) including negatives, separated by commas or semicolons.', ...
                            'Invalid Input', ...
                            'Icon', 'warning');
                    end

                case 'Trajectory_2D'
                    % No arguments needed for Trajectory_2D
                    is_valid = true;

                otherwise
                    % Handle unexpected program selection
                    uialert(app.UIFigure, ...
                        'Unexpected program selected.', ...
                        'Error', ...
                        'Icon', 'error');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            screenSize = get(0, 'ScreenSize'); % Get the screen size
            appWidth = screenSize(3) / 3; % App width is 1/3 of the screen width
            appHeight = screenSize(4); % App height is the full screen height

            % Set the position of the app
            app.UIFigure.Position = [screenSize(3) - appWidth, 1, appWidth, appHeight];

            % Set UIFigure to visible
            app.UIFigure.Visible = 'on';

            % Add Path to Launcher
            currentDir = fileparts(mfilename('fullpath'));
            launcherPath = fullfile(currentDir, 'src', 'Programs');
            addpath(launcherPath);

            app.updateGUI;

        end

        % Button pushed function: ConnectDisconnectButton
        function ConnectDisconnectButtonPushed(app, event)


            % Connect Button
            if strcmp(app.ConnectDisconnectButton.Text, 'Connect')
                % Get Launcher Instance
                app.launcher = Launcher.getInstance;
                app.TorqueSwitch.Value = 'On';
            end

            % Disconnect Button
            if strcmp(app.ConnectDisconnectButton.Text, 'Disconnect')
                app.launcher.status = 'ready';
                app.updateGUI;
                %Delete and Remove launcher Instance
                delete(app.launcher)
                app.launcher = [];
            end

            app.updateGUI;

        end

        % Value changed function: TorqueSwitch
        function TorqueSwitchValueChanged(app, event)
            app.launcher.status = 'ready';

            % Torque Switch Slided Towards On
            if strcmp(app.TorqueSwitch.Value, 'On')
                app.launcher.realRobot.torqueEnable;
                % And open the control panel
            else
                app.launcher.realRobot.torqueDisable;
            end
            app.updateGUI;
        end

        % Value changed function: ProgramSelectDropdown
        function ProgramSelectDropdownValueChanged(app, event)
            % Value changed function: ProgramDropDown
            selectedProgram = app.ProgramSelectDropdown.Value;

            % Clear the Program Arguments Field
            app.ProgramArgumentsEditField.Value = '';

            % Handle different cases based on the selected program
            switch selectedProgram
                case 'Set_Joints'
                    app.ProgramArgumentsPanel.Visible = true;
                    app.ProgramArgumentsLabel.Text = 'Joint Angles [deg]';
                    app.ProgramArgumentsEditField.Placeholder = 'q1, q2, q3, q4';

                case 'Set_Position'
                    app.ProgramArgumentsPanel.Visible = true;
                    app.ProgramArgumentsLabel.Text = 'TCP Position [mm]';
                    app.ProgramArgumentsEditField.Placeholder = 'x, y, z';

                case 'Trajectory_2D'
                    app.ProgramArgumentsPanel.Visible = false;
                    app.ProgramArgumentsLabel.Text = '';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            if strcmp(app.ConnectDisconnectButton.Text, 'Disconnect')
                app.ConnectDisconnectButtonPushed;
            end
            delete(app.UIFigure);


        end

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            app.launcher.launchProgram('Set_Joints',[0.3;0.3;0.5;0.5])
            app.updateGUI;
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            app.launcher.launchProgram('Set_Joints',[0;0;0;0])
            app.updateGUI;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)

            % Method to handle the Go button push event
            % Check if the supplied arguments in the ProgramArgumentsEditField are in the correct format and valid
            is_valid = app.checkProgramArguments;

            if is_valid
                % Extract the selected program
                selectedProgram = app.ProgramSelectDropdown.Value;
                % Get arguments as a string
                argumentsStr = app.ProgramArgumentsEditField.Value;

                % Convert string arguments to numeric array
                numericArguments = str2double(split(regexprep(argumentsStr, '[,;]', ' '), ' '));

                % Reshape numericArguments to ensure it is a column vector
                numericArguments = reshape(numericArguments, [], 1);

                % Call the appropriate program with the arguments
                switch selectedProgram
                    case 'Set_Joints'
                        % Transform degree to rad
                        numericArguments_rad = deg2rad(numericArguments);
                        app.launcher.launchProgram('Set_Joints', numericArguments_rad);

                    case 'Set_Position'
                        app.launcher.launchProgram('Set_Position', numericArguments);

                    case 'Trajectory_2D'
                        app.launcher.launchProgram('Trajetory_2D', numericArguments);

                    otherwise
                        % Handle unexpected program selection
                        uialert(app.UIFigure, ...
                            'Unexpected program selected.', ...
                            'Error', ...
                            'Icon', 'error');
                end
            end

            app.updateGUI;


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.launcher.status = 'ready';
            app.updateGUI;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectDisconnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectDisconnectButtonPushed, true);
            app.ConnectDisconnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port:';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8'};
            app.PortDropDown.Value = 'COM1';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Visible = 'off';
            app.ProgramSelectPanel = uipanel(app.ControlPanel);
            app.ProgramSelectDropdown = uidropdown(app.ProgramSelectPanel);
            app.ProgramSelectDropdown.ValueChangedFcn = createCallbackFcn(app, @ProgramSelectDropdownValueChanged, true);
            app.ProgramSelectLabel = uilabel(app.ProgramSelectPanel);
            app.ProgramSelectLabel.Text = 'Program';
            app.ProgramArgumentsPanel = uipanel(app.ControlPanel);
            app.ProgramArgumentsPanel.Title = 'Panel';
            app.ProgramArgumentsEditField = uieditfield(app.ProgramArgumentsPanel, 'text');
            app.ProgramArgumentsEditField.Placeholder = 'q1, q2, q3, q4';
            app.ProgramArgumentsLabel = uilabel(app.ProgramArgumentsPanel);
            app.ProgramArgumentsLabel.Text = 'Value';
            app.InitButton = uibutton(app.ControlPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.ZeroButton = uibutton(app.ControlPanel, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.GoButton = uibutton(app.ControlPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Panel2';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Text = 'Disconnected';
            app.TorqueSwitchLabel = uilabel(app.UIFigure);
            app.TorqueSwitchLabel.Visible = 'off';
            app.TorqueSwitchLabel.Text = 'Torque';
            app.TorqueSwitch = uiswitch(app.UIFigure, 'slider');
            app.TorqueSwitch.ValueChangedFcn = createCallbackFcn(app, @TorqueSwitchValueChanged, true);
            app.TorqueSwitch.Visible = 'off';
            app.TorqueSwitch.Value = 'On';
            app.SingularityWarningLabel = uilabel(app.UIFigure);
            app.SingularityWarningLabel.Text = 'Singularity Warning';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end